classdef beranda < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TentangKamiButton        matlab.ui.control.Button
        TutorialAplikasiButton   matlab.ui.control.Button
        ApaItuPegaganButton      matlab.ui.control.Button
        MulaiIdentifikasiButton  matlab.ui.control.Button
        Image                    matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center');
            
            t       = 0;
            tMax    = 5;
            dt      = 0.1;
            while ~isfield(struct(struct(app.UIFigure).Controller), 'PlatformHost') && t < tMax
                % Keep waiting until the window is found.
                t = t + dt;
                pause(dt)
            end
            win = struct(struct(struct(app.UIFigure).Controller).PlatformHost).CEF;
            win.Icon = 'D:\Tugas - Project\Clone\Wibu\PCV Project\GUIfinal\leaf3.ico';
        end

        % Callback function
        function JalankanAplikasiButtonPushed(app, event)
            
        end

        % Button pushed function: MulaiIdentifikasiButton
        function MulaiIdentifikasiButtonPushed2(app, event)
            delete(app.UIFigure);
            proses
        end

        % Button pushed function: ApaItuPegaganButton
        function ApaItuPegaganButtonPushed(app, event)
            delete(app.UIFigure);
            apatu
        end

        % Button pushed function: TentangKamiButton
        function TentangKamiButtonPushed(app, event)
            delete(app.UIFigure);
            tentangkami
        end

        % Button pushed function: TutorialAplikasiButton
        function TutorialAplikasiButtonPushed(app, event)
            dos('explorer https://youtu.be/Yxz37LolXeE');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = '2.png';
            app.MulaiIdentifikasiButton = uibutton(app.UIFigure, 'push');
            app.MulaiIdentifikasiButton.ButtonPushedFcn = createCallbackFcn(app, @MulaiIdentifikasiButtonPushed2, true);
            app.MulaiIdentifikasiButton.Text = 'Mulai Identifikasi';
            app.ApaItuPegaganButton = uibutton(app.UIFigure, 'push');
            app.ApaItuPegaganButton.ButtonPushedFcn = createCallbackFcn(app, @ApaItuPegaganButtonPushed, true);
            app.ApaItuPegaganButton.Text = 'Apa Itu Pegagan?';
            app.TutorialAplikasiButton = uibutton(app.UIFigure, 'push');
            app.TutorialAplikasiButton.ButtonPushedFcn = createCallbackFcn(app, @TutorialAplikasiButtonPushed, true);
            app.TutorialAplikasiButton.Text = 'Tutorial Aplikasi';
            app.TentangKamiButton = uibutton(app.UIFigure, 'push');
            app.TentangKamiButton.ButtonPushedFcn = createCallbackFcn(app, @TentangKamiButtonPushed, true);
            app.TentangKamiButton.Text = 'Tentang Kami';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = beranda

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end