classdef tentangkami < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        KembalikeBerandaButton  matlab.ui.control.Button
        Image                   matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center');
            t       = 0;
            tMax    = 5;
            dt      = 0.1;
            while ~isfield(struct(struct(app.UIFigure).Controller), 'PlatformHost') && t < tMax
                % Keep waiting until the window is found.
                t = t + dt;
                pause(dt)
            end
            win = struct(struct(struct(app.UIFigure).Controller).PlatformHost).CEF;
            win.Icon = 'D:\Tugas - Project\Clone\Wibu\PCV Project\GUIfinal\leaf3.ico';
        end

        % Button pushed function: KembalikeBerandaButton
        function KembalikeBerandaButtonPushed(app, event)
            delete(app.UIFigure);
            beranda
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'tentang.png';
            app.KembalikeBerandaButton = uibutton(app.UIFigure, 'push');
            app.KembalikeBerandaButton.ButtonPushedFcn = createCallbackFcn(app, @KembalikeBerandaButtonPushed, true);
            app.KembalikeBerandaButton.Text = 'Kembali ke Beranda';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tentangkami

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end